classdef BasicApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputTab                       matlab.ui.container.Tab
        ApogeeAltitudeEditFieldLabel   matlab.ui.control.Label
        ApogeeAltitudeEditField        matlab.ui.control.NumericEditField
        PerigeeAltitudeEditFieldLabel  matlab.ui.control.Label
        PerigeeAltitudeEditField       matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        CreatescenarioButton           matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        OuputTab                       matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreatescenarioButton
        function CreatescenarioButtonPushed(app, event)
            apogee = app.ApogeeAltitudeEditField.Value;
            perigee = app.PerigeeAltitudeEditField.Value;
            inclination = app.InclinationEditField.Value;
            
            %% Inital Setup

            % Connect to STK
            ap = actxserver('STK12.application');
            root = ap.Personality2;

            % Scenario Setup
            scenario = root.Children.New('eScenario','MATLAB_AurisOrbit');

            %scenario.SetTimePeriod('1 Mar 2023 00:00:00.000','1 Mar 2023 06:00:00.000'); %Scenario time period
            scenario.SetTimePeriod('1 Mar 2023 00:00:00.000','1 Mar 2024 00:00:00.000'); %Scenario time period
            scenario.StartTime = '1 Mar 2023 00:00:00.000'; %Scenario start time

            %scenario.StopTime = '1 Mar 2023 06:00:00.000'; %Scenario end time
            scenario.StopTime = '1 Mar 2024 00:00:00.000'; %Scenario end time
            root.ExecuteCommand('Animate * Reset');
            root.UnitPreferences.Item('DateFormat').SetCurrentUnit('EpSec'); %Sets time unit to epsec

            %% Satellite Setup
            satellite = scenario.Children.New('eSatellite','Auris');  %creates the auris satellite object
            model = satellite.VO.Model; %shortcut to modify satellite model properties

            % Define model file location (should be a .dae file with a .anc file of the same name in the same folder)
            model.ModelData.Filename = 'G:\Shared drives\MTU Aerospace\(2) Auris\(2) Mission Level\(1) Mission Operations [MOP]\(3) Illumination Analysis\auris9u.dae';
            keplerian = satellite.Propagator.InitialState.Representation.ConvertTo('eOrbitStateClassical'); % Use the Classical Element interface
            keplerian.SizeShapeType = 'eSizeShapeAltitude';  % Changes from Ecc/Inc to Perigee/Apogee Altitude
            keplerian.LocationType = 'eLocationTrueAnomaly'; % Makes sure True Anomaly is being used
            keplerian.Orientation.AscNodeType = 'eAscNodeLAN'; % Use LAN instead of RAAN for data entry

            % Assign the perigee and apogee altitude values:
            keplerian.SizeShape.PerigeeAltitude = perigee;      % km
            keplerian.SizeShape.ApogeeAltitude = apogee;        % km

            % Assign the other desired orbital parameters:
            keplerian.Orientation.Inclination = inclination;    % deg
            keplerian.Orientation.ArgOfPerigee = 0;             % deg
            keplerian.Orientation.AscNode.Value =0;             % deg
            keplerian.Location.Value = 0;                       % deg

            % Apply the changes made to the satellite's state and propagate:
            satellite.Propagator.InitialState.Representation.Assign(keplerian);
            satellite.Propagator.Propagate;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.ApogeeAltitudeEditFieldLabel = uilabel(app.InputTab);
            app.ApogeeAltitudeEditFieldLabel.Text = 'Apogee Altitude';
            app.ApogeeAltitudeEditField = uieditfield(app.InputTab, 'numeric');
            app.PerigeeAltitudeEditFieldLabel = uilabel(app.InputTab);
            app.PerigeeAltitudeEditFieldLabel.Text = 'Perigee Altitude';
            app.PerigeeAltitudeEditField = uieditfield(app.InputTab, 'numeric');
            app.InclinationEditFieldLabel = uilabel(app.InputTab);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.InputTab, 'numeric');
            app.CreatescenarioButton = uibutton(app.InputTab, 'push');
            app.CreatescenarioButton.ButtonPushedFcn = createCallbackFcn(app, @CreatescenarioButtonPushed, true);
            app.CreatescenarioButton.Text = 'Create scenario';
            app.Image = uiimage(app.InputTab);
            app.Image.ImageSource = 'aerospace-logo.png';
            app.OuputTab = uitab(app.TabGroup);
            app.OuputTab.Title = 'Ouput';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end